classdef malatag_doe < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ChronoWaveUIFigure      matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        MALATAGCPE122L4C1Label  matlab.ui.control.Label
        GridLayout3             matlab.ui.container.GridLayout
        TIMESIGNALLabel         matlab.ui.control.Label
        RESULT                  matlab.ui.control.EditField
        EVALUATIONLabel         matlab.ui.control.Label
        INPUT                   matlab.ui.control.EditField
        INPUTEditFieldLabel     matlab.ui.control.Label
        GridLayout2             matlab.ui.container.GridLayout
        STABILITYButton         matlab.ui.control.Button
        INVARIANCEButton        matlab.ui.control.Button
        LINEARITYButton         matlab.ui.control.Button
        CAUSALITYButton         matlab.ui.control.Button
        ENERGYButton            matlab.ui.control.Button
        STATICButton            matlab.ui.control.Button
        TESTERSLabel            matlab.ui.control.Label
        Image                   matlab.ui.control.Image
    end

    methods (Access = public)

        function signal = getInputSignal(app)
            try
                % Ensure the input is a string or cell array of character vectors
                if isnumeric(app.INPUT.Value)
                    inputSignal = string(app.INPUT.Value);
                else
                    inputSignal = strtrim(strjoin(string(app.INPUT.Value), ' '));
                end
                
                if isempty(inputSignal)
                    app.RESULT.Value = 'Error: Empty input';
                    signal = [];
                    return;
                end
        
                inputPattern = '(y|x)\(n\)\s*=\s*(.*)'; 
                tokens = regexp(inputSignal, inputPattern, 'tokens');
        
                if ~isempty(tokens)
                    inputSignal = strtrim(tokens{1}{2}); 
                else
                    app.RESULT.Value = 'Error: Invalid input format. Use "y(n) =" or "x(n) ="';
                    signal = [];
                    return;
                end
        
                syms x(n) n
        
                inputSignal = strrep(inputSignal, '^', '.^'); 
                inputSignal = strrep(inputSignal, '*', '.*'); 
                inputSignal = strrep(inputSignal, '/', './'); 
                signal = str2sym(inputSignal);
        
            catch ME
                app.RESULT.Value = ['Error: ', ME.message];
                signal = [];
            end
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: STATICButton
        function STATICButtonPushed(app, event)
            signal = app.getInputSignal();

            app.STATICButton.BackgroundColor = "#998b7c"
            app.ENERGYButton.BackgroundColor = "#cfbeab"
            app.CAUSALITYButton.BackgroundColor = "#cfbeab"
            app.LINEARITYButton.BackgroundColor = "#cfbeab"
            app.INVARIANCEButton.BackgroundColor = "#cfbeab"
            app.STABILITYButton.BackgroundColor = "#cfbeab"
            app.STATICButton.FontColor = "#aa4328"
            app.ENERGYButton.FontColor = "#2e2e2d"
            app.CAUSALITYButton.FontColor = "#2e2e2d"
            app.LINEARITYButton.FontColor = "#2e2e2d"
            app.INVARIANCEButton.FontColor = "#2e2e2d"
            app.STABILITYButton.FontColor = "#2e2e2d"
            
            expr = char(signal);
            disp(['Processed expression: ', expr]); % Debugging line
            
            % Extract all x(...) terms and their indices
            xIndices = regexp(expr, 'x\s*\(\s*([^)]*)\s*\)', 'tokens');
            
            isNonCausal = false;
            
            % Causal pattern: matches 'n' or 'n - k' where k is a non-negative integer
            causalPattern = '^\s*n\s*(-\s*\d+\s*)?$';
            
            for i = 1:length(xIndices)
                indexStr = strtrim(xIndices{i}{1});
                if isempty(regexp(indexStr, causalPattern, 'once'))
                    isNonCausal = true;
                    break;
                end
            end
            
            if isNonCausal
                app.RESULT.Value = 'Non-Causal';
            else
                app.RESULT.Value = 'Causal';
            end
        end

        % Button pushed function: ENERGYButton
        function ENERGYButtonPushed(app, event)
            signal = app.getInputSignal();

            app.STATICButton.BackgroundColor = "#cfbeab"
            app.ENERGYButton.BackgroundColor = "#998b7c"
            app.CAUSALITYButton.BackgroundColor = "#cfbeab"
            app.LINEARITYButton.BackgroundColor = "#cfbeab"
            app.INVARIANCEButton.BackgroundColor = "#cfbeab"
            app.STABILITYButton.BackgroundColor = "#cfbeab"
            app.STATICButton.FontColor = "#2e2e2d"
            app.ENERGYButton.FontColor = "#aa4328"
            app.CAUSALITYButton.FontColor = "#2e2e2d"
            app.LINEARITYButton.FontColor = "#2e2e2d"
            app.INVARIANCEButton.FontColor = "#2e2e2d"
            app.STABILITYButton.FontColor = "#2e2e2d"
        
            syms n integer;
            syms x(n);
            
            try
                % Define δ(n) symbolically
                delta(n) = kroneckerDelta(n, 0);
                
                % Compute impulse response h(n) = T[δ(n)]
                % Replace x(n) and x(-n) with symbolic δ(n) and δ(-n)
                h(n) = subs(signal, {x(n), x(-n)}, {delta(n), delta(-n)});
                
                % Numerically evaluate energy over a large range
                N = 1000;
                energy = 0;
                for k = -N:N
                    % Substitute n = k and evaluate h(k)
                    h_k = double(subs(h(n), n, k));
                    energy = energy + abs(h_k)^2;
                end
                
                % Classify based on energy
                if isfinite(energy) && energy < Inf
                    app.RESULT.Value = 'Energy Signal';
                 
                else
                    % Compute average power for IIR systems
                    N_power = 10000;
                    power = sum(abs(double(subs(h(n), n, -N_power:N_power))).^2 / (2*N_power + 1));
                    if isfinite(power)
                        app.RESULT.Value = 'Power Signal';
                        %app.ExplanationLabel.Text = sprintf('Average power = %.4f', power);
                    else
                        app.RESULT.Value = 'Neither';
                        %app.ExplanationLabel.Text = 'Unbounded energy/power';
                    end
                end
                
            catch ME
                app.RESULT.Value = ['Error: ', ME.message];
                %app.ExplanationLabel.Text = ['Energy Test Error: ', ME.message];
            end
        end

        % Button pushed function: CAUSALITYButton
        function CAUSALITYButtonPushed(app, event)
            signal = app.getInputSignal();

            app.STATICButton.BackgroundColor = "#cfbeab"
            app.ENERGYButton.BackgroundColor = "#cfbeab"
            app.CAUSALITYButton.BackgroundColor = "#998b7c"
            app.LINEARITYButton.BackgroundColor = "#cfbeab"
            app.INVARIANCEButton.BackgroundColor = "#cfbeab"
            app.STABILITYButton.BackgroundColor = "#cfbeab"
            app.STATICButton.FontColor = "#2e2e2d"
            app.ENERGYButton.FontColor = "#2e2e2d"
            app.CAUSALITYButton.FontColor = "#aa4328"
            app.LINEARITYButton.FontColor = "#2e2e2d"
            app.INVARIANCEButton.FontColor = "#2e2e2d"
            app.STABILITYButton.FontColor = "#2e2e2d"

            expr = char(signal);
            disp(['Processed expression: ', expr]); % Debugging line
        
            % Enhanced regex to detect:
            % 1. x(n+k) where k > 0
            % 2. x(-n + k) which is equivalent to x(k - n) (future dependency)
            antiCausalTerms = regexp(expr, 'x\s*\(\s*(?:n\s*\+\s*\d+|-\s*n\s*\+\s*\d+|\d+\s*-\s*n)\s*\)', 'match');
        
            if ~isempty(antiCausalTerms)
                app.RESULT.Value = 'Anti-Causal'; 
                % app.ExplanationLabel.Text = 'Anti-Causal: Depends on future inputs (e.g., x(n+k), x(-n+k), x(k-n)).';
            else
                app.RESULT.Value = 'Causal';
                % app.ExplanationLabel.Text = 'Causal: Depends only on present/past inputs.';
            end
        end

        % Button pushed function: LINEARITYButton
        function LINEARITYButtonPushed(app, event)
            signal = app.getInputSignal();

            app.STATICButton.BackgroundColor = "#cfbeab"
            app.ENERGYButton.BackgroundColor = "#cfbeab"
            app.CAUSALITYButton.BackgroundColor = "#cfbeab"
            app.LINEARITYButton.BackgroundColor = "#998b7c"
            app.INVARIANCEButton.BackgroundColor = "#cfbeab"
            app.STABILITYButton.BackgroundColor = "#cfbeab"
            app.STATICButton.FontColor = "#2e2e2d"
            app.ENERGYButton.FontColor = "#2e2e2d"
            app.CAUSALITYButton.FontColor = "#2e2e2d"
            app.LINEARITYButton.FontColor = "#aa4328"
            app.INVARIANCEButton.FontColor = "#2e2e2d"
            app.STABILITYButton.FontColor = "#2e2e2d"
            
            syms n integer;
            syms x(n) x1(n) x2(n) a b;
            
            try
                T_ax1_bx2 = subs(signal, x, a*x1 + b*x2);
                T_x1 = subs(signal, x, x1);
                T_x2 = subs(signal, x, x2);
                aT_plus_bT = a*T_x1 + b*T_x2;
                
               
                isLinear = logical(isAlways(T_ax1_bx2 == aT_plus_bT));
                
               
                if isLinear
                    app.RESULT.Value = 'Linear';
                    % app.ExplanationLabel.Text = 'Linear: Satisfies superposition principle.';
                else
                    app.RESULT.Value = 'Non-Linear';
                    % app.ExplanationLabel.Text = 'Non-Linear: Fails superposition.';
                end
                
            catch ME
                app.RESULT.Value = ['Error: ', ME.message];
            end
        end

        % Button pushed function: INVARIANCEButton
        function INVARIANCEButtonPushed(app, event)
            signal = app.getInputSignal();

            app.STATICButton.BackgroundColor = "#cfbeab"
            app.ENERGYButton.BackgroundColor = "#cfbeab"
            app.CAUSALITYButton.BackgroundColor = "#cfbeab"
            app.LINEARITYButton.BackgroundColor = "#cfbeab"
            app.INVARIANCEButton.BackgroundColor = "#998b7c"
            app.STABILITYButton.BackgroundColor = "#cfbeab"
            app.STATICButton.FontColor = "#2e2e2d"
            app.ENERGYButton.FontColor = "#2e2e2d"
            app.CAUSALITYButton.FontColor = "#2e2e2d"
            app.LINEARITYButton.FontColor = "#2e2e2d"
            app.INVARIANCEButton.FontColor = "#aa4328"
            app.STABILITYButton.FontColor = "#2e2e2d"
            
            syms n k integer;
            syms x(n);
            
            try

                x_shift(n) = x(n - k);
                T_x_shift = subs(signal, x(n), x_shift); 
                y_shifted = subs(signal, n, n - k);     
                
                isInvariant = logical(isAlways(T_x_shift == y_shifted, 'Unknown', 'false'));
                
                if isInvariant
                    app.RESULT.Value = 'Time Invariant';
                    % app.ExplanationLabel.Text = 'Time Invariant: Output shifts with input delay.';
                else
                    app.RESULT.Value = 'Time Variant'; % Correctly flags x(-n) as variant
                    % app.ExplanationLabel.Text = 'Time Variant: Output changes with input delay.';
                end
                
            catch ME
                app.RESULT.Value = ['Error: ', ME.message];
            end
        end

        % Button pushed function: STABILITYButton
        function STABILITYButtonPushed(app, event)
            signal = app.getInputSignal();

            app.STATICButton.BackgroundColor = "#cfbeab"
            app.ENERGYButton.BackgroundColor = "#cfbeab"
            app.CAUSALITYButton.BackgroundColor = "#cfbeab"
            app.LINEARITYButton.BackgroundColor = "#cfbeab"
            app.INVARIANCEButton.BackgroundColor = "#cfbeab"
            app.STABILITYButton.BackgroundColor = "#998b7c"
            app.STATICButton.FontColor = "#2e2e2d"
            app.ENERGYButton.FontColor = "#2e2e2d"
            app.CAUSALITYButton.FontColor = "#2e2e2d"
            app.LINEARITYButton.FontColor = "#2e2e2d"
            app.INVARIANCEButton.FontColor = "#2e2e2d"
            app.STABILITYButton.FontColor = "#aa4328"
        
            expr = char(signal);
        
            unstableTerms = regexp(expr, 'n\s*[\*\.\*]\s*x\(.*?\)|n\^|\d+\^n|exp\(n\)|factorial\(n\)|\(?\s*x\s*\(\s*n\s*\)\s*\)?\s*\^\s*n', 'match');
        
            if ~isempty(unstableTerms)
                app.RESULT.Value = 'Unstable';
                % app.ExplanationLabel.Text = 'Unstable: Contains terms like n*x(n±k), a^n*x(n), (x(n))^n, or factorial(n).';
            else
                try
                    N = 1000;
                    x_test = ones(1, N);
                    n_values = 0:N-1;
                    y = double(subs(signal, {n, x(n)}, {n_values, x_test}));
                    
                    if ~all(isfinite(y))
                        app.RESULT.Value = 'Unstable';
                        % app.ExplanationLabel.Text = 'Unstable: Output contains Inf/NaN.';
                    else
                        threshold = 100; 
                        if max(abs(y)) > threshold
                            app.RESULT.Value = 'Unstable';
                            % app.ExplanationLabel.Text = 'Unstable: Output grows unbounded.';
                        else
                            app.RESULT.Value = 'Stable';
                            % app.ExplanationLabel.Text = 'Stable: BIBO confirmed.';
                        end
                    end
                catch
                    app.RESULT.Value = 'Stable (Assumed)';
                    % app.ExplanationLabel.Text = 'Stable: No instability terms detected.';
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.ChronoWaveUIFigure = uifigure('Visible', 'off');
            app.ChronoWaveUIFigure.Name = 'ChronoWave';
            app.GridLayout = uigridlayout(app.ChronoWaveUIFigure);
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Assets', 'title.png');
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.TESTERSLabel = uilabel(app.GridLayout2);
            app.TESTERSLabel.Text = 'T E S T E R S';
            app.STATICButton = uibutton(app.GridLayout2, 'push');
            app.STATICButton.ButtonPushedFcn = createCallbackFcn(app, @STATICButtonPushed, true);
            app.STATICButton.Text = 'STATIC';
            app.ENERGYButton = uibutton(app.GridLayout2, 'push');
            app.ENERGYButton.ButtonPushedFcn = createCallbackFcn(app, @ENERGYButtonPushed, true);
            app.ENERGYButton.Text = 'ENERGY';
            app.CAUSALITYButton = uibutton(app.GridLayout2, 'push');
            app.CAUSALITYButton.ButtonPushedFcn = createCallbackFcn(app, @CAUSALITYButtonPushed, true);
            app.CAUSALITYButton.Text = 'CAUSALITY';
            app.LINEARITYButton = uibutton(app.GridLayout2, 'push');
            app.LINEARITYButton.ButtonPushedFcn = createCallbackFcn(app, @LINEARITYButtonPushed, true);
            app.LINEARITYButton.Text = 'LINEARITY';
            app.INVARIANCEButton = uibutton(app.GridLayout2, 'push');
            app.INVARIANCEButton.ButtonPushedFcn = createCallbackFcn(app, @INVARIANCEButtonPushed, true);
            app.INVARIANCEButton.Text = 'INVARIANCE';
            app.STABILITYButton = uibutton(app.GridLayout2, 'push');
            app.STABILITYButton.ButtonPushedFcn = createCallbackFcn(app, @STABILITYButtonPushed, true);
            app.STABILITYButton.Text = 'STABILITY';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.INPUTEditFieldLabel = uilabel(app.GridLayout3);
            app.INPUTEditFieldLabel.Text = 'INPUT';
            app.INPUT = uieditfield(app.GridLayout3, 'text');
            app.EVALUATIONLabel = uilabel(app.GridLayout3);
            app.EVALUATIONLabel.Text = 'EVALUATION';
            app.RESULT = uieditfield(app.GridLayout3, 'text');
            app.TIMESIGNALLabel = uilabel(app.GridLayout3);
            app.TIMESIGNALLabel.Text = 'T I M E  S I G N A L';
            app.MALATAGCPE122L4C1Label = uilabel(app.GridLayout);
            app.MALATAGCPE122L4C1Label.Text = {'MALATAG'; 'CPE122L-4 C1'};
            % Show the figure after all components are created
            app.ChronoWaveUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = malatag_doe

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ChronoWaveUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ChronoWaveUIFigure)
        end
    end
end